classdef MattMoran_Exam3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Communication_Lab          matlab.ui.Figure
        RGBLEDDropDownLabel        matlab.ui.control.Label
        RGBLEDDropDown             matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        PhotocellReadingLabel      matlab.ui.control.Label
        PotentiometerReadingLabel  matlab.ui.control.Label
        PhotocellReadingText       matlab.ui.control.TextArea
        PotentiometerReadingText   matlab.ui.control.TextArea
        RedLEDPanel                matlab.ui.container.Panel
        RedOffButton               matlab.ui.control.Button
        RedOnButton                matlab.ui.control.Button
        YellowLEDPanel             matlab.ui.container.Panel
        YellowOffButton            matlab.ui.control.Button
        YellowOnButton             matlab.ui.control.Button
        GreenLEDPanel              matlab.ui.container.Panel
        GreenOffButton             matlab.ui.control.Button
        GreenOnButton              matlab.ui.control.Button
        BlueLEDPanel               matlab.ui.container.Panel
        BlueOffButton              matlab.ui.control.Button
        BlueOnButton               matlab.ui.control.Button
        PushbuttonCountersLabel    matlab.ui.control.Label
        RedCounter                 matlab.ui.control.Label
        YellowCounter              matlab.ui.control.Label
        GreenCounter               matlab.ui.control.Label
        BlueCounter                matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        s = serial('COM10', 'Baudrate', 9600); % Description
        redCount = 0; % Description
        yellowCount = 0;
        greenCount = 0;
        blueCount = 0;
        currentRBG = 'off';
        redpushed = 'false'; % Description
        yellowpushed = 'false';
        greenpushed = 'false';
        bluepushed = 'false';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            fopen(app.s);
            fprintf('Running\n');
            pause(1);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            fclose(app.s);
            fprintf('Closing\n');
            pause(1);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            fprintf('Updating Values\n');
            fprintf(app.s,'Update\n');
            pause(1);
           % app.PhotocellReadingText.Value = ;
           % app.PotentiometerReadingText.Value = ;
        end

        % Value changed function: RGBLEDDropDown
        function RGBLEDDropDownValueChanged(app, event)
            app.currentRBG = app.RGBLEDDropDown.Value;
            if(strcmp(app.currentRBG,"Off"))
                fprintf(app.s,'Off\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Blue"))
                fprintf(app.s,'Blue\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Green"))
                fprintf(app.s,'Green\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Cyan"))
                fprintf(app.s,'Cyan\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Red"))
                fprintf(app.s,'Red\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Purple")) 
                fprintf(app.s,'Purple\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Yellow"))
                fprintf(app.s,'Yellow\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"White"))
                fprintf(app.s,'White\n');
                pause(1);
            end
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            fprintf(app.s,'ROFF\n');
            pause(1);
            app.redpushed = 'false';
        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
           fprintf(app.s,'RON\n');
           pause(1);  
           app.redpushed = 'true';
           app.RedCounter.Text = str2num(app.RedCounter.Text) + 1;
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
           fprintf(app.s,'YOFF\n');
           pause(1);
           app.yellowpushed = 'false';
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
           fprintf(app.s,'YON\n');
           pause(1);
           app.yellowpushed = 'true';
           app.YellowCounter.Text = str2num(app.YellowCounter.Text) + 1;
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
           fprintf(app.s,'GOFF\n');
           pause(1);
           app.greenpushed = 'false';
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
           fprintf(app.s,'GON\n');
           pause(1);
           app.greenpushed = 'true';
           app.GreenCounter.Text = str2num(app.GreenCounter.Text) + 1;
        end

        % Button pushed function: BlueOffButton
        function BlueOffButtonPushed(app, event)
           fprintf(app.s,'BOFF\n');
           pause(1);
           app.bluepushed = 'false';
        end

        % Button pushed function: BlueOnButton
        function BlueOnButtonPushed(app, event)
           fprintf(app.s,'BON\n');
           pause(1);
           app.bluepushed = 'true';
           app.BlueCounter.Text = str2num(app.BlueCounter.Text) + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Communication_Lab = uifigure('Visible', 'off');
            app.Communication_Lab.Name = 'UI Figure';
            app.RGBLEDDropDownLabel = uilabel(app.Communication_Lab);
            app.RGBLEDDropDownLabel.Text = 'RGB LED';
            app.RGBLEDDropDown = uidropdown(app.Communication_Lab);
            app.RGBLEDDropDown.Items = {'Off', 'Blue', 'Green', 'Cyan', 'Red', 'Purple', 'Yellow', 'White'};
            app.RGBLEDDropDown.ValueChangedFcn = createCallbackFcn(app, @RGBLEDDropDownValueChanged, true);
            app.RGBLEDDropDown.Value = 'Off';
            app.ConnectButton = uibutton(app.Communication_Lab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.Communication_Lab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PhotocellReadingLabel = uilabel(app.Communication_Lab);
            app.PhotocellReadingLabel.Text = 'Photocell Reading';
            app.PotentiometerReadingLabel = uilabel(app.Communication_Lab);
            app.PotentiometerReadingLabel.Text = 'Potentiometer Reading';
            app.PhotocellReadingText = uitextarea(app.Communication_Lab);
            app.PotentiometerReadingText = uitextarea(app.Communication_Lab);
            app.RedLEDPanel = uipanel(app.Communication_Lab);
            app.RedLEDPanel.Title = 'Red LED';
            app.RedOffButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Off';
            app.RedOnButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'On';
            app.YellowLEDPanel = uipanel(app.Communication_Lab);
            app.YellowLEDPanel.Title = 'Yellow LED';
            app.YellowOffButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Off';
            app.YellowOnButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'On';
            app.GreenLEDPanel = uipanel(app.Communication_Lab);
            app.GreenLEDPanel.Title = 'Green LED';
            app.GreenOffButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Off';
            app.GreenOnButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'On';
            app.BlueLEDPanel = uipanel(app.Communication_Lab);
            app.BlueLEDPanel.Title = 'Blue LED';
            app.BlueOffButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOffButton.ButtonPushedFcn = createCallbackFcn(app, @BlueOffButtonPushed, true);
            app.BlueOffButton.Text = 'Off';
            app.BlueOnButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOnButton.ButtonPushedFcn = createCallbackFcn(app, @BlueOnButtonPushed, true);
            app.BlueOnButton.Text = 'On';
            app.PushbuttonCountersLabel = uilabel(app.Communication_Lab);
            app.PushbuttonCountersLabel.Text = 'Pushbutton Counters';
            app.RedCounter = uilabel(app.Communication_Lab);
            app.RedCounter.Text = '0';
            app.YellowCounter = uilabel(app.Communication_Lab);
            app.YellowCounter.Text = '0';
            app.GreenCounter = uilabel(app.Communication_Lab);
            app.GreenCounter.Text = '0';
            app.BlueCounter = uilabel(app.Communication_Lab);
            app.BlueCounter.Text = '0';
            app.UpdateButton = uibutton(app.Communication_Lab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.Communication_Lab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MattMoran_Exam3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Communication_Lab)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Communication_Lab)
        end
    end
end